classdef SDEMapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TextArea                    matlab.ui.control.TextArea
        k                           matlab.ui.control.NumericEditField
        MasstransfercoefficientkLMHLabel  matlab.ui.control.Label
        jv                          matlab.ui.control.EditField
        JvLMHEditFieldLabel         matlab.ui.control.Label
        rej2                        matlab.ui.control.EditField
        Mg2rejectionEditFieldLabel  matlab.ui.control.Label
        rej1                        matlab.ui.control.EditField
        LirejectionEditFieldLabel   matlab.ui.control.Label
        conc2                       matlab.ui.control.EditField
        Mg2concmMEditFieldLabel     matlab.ui.control.Label
        conc1                       matlab.ui.control.EditField
        LiconcmMEditFieldLabel      matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        CalculateButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            cfli = str2num(app.conc1.Value);
            cfmg = str2num(app.conc2.Value);
            Rli = str2num(app.rej1.Value)/100;
            Rmg = str2num(app.rej2.Value)/100;
            jv = str2num(app.jv.Value)/3.6;
            k = app.k.Value;
            [plilist,pmglist] = main(cfli, cfmg, jv, Rli, Rmg, k);
            data = [cfli;cfmg;Rli*100;Rmg*100;jv*3.6;plilist;pmglist]';
            app.UITable.Data = data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Li+ conc (mM)'; 'Mg2+ conc (mM)'; 'Li+ rejection (%)'; 'Mg2+ rejection (%)'; 'Jv (LMH)'; 'PLi+ (um/s)'; 'PMg2+ (um/s)'};
            app.UITable.RowName = {};
            app.LiconcmMEditFieldLabel = uilabel(app.UIFigure);
            app.LiconcmMEditFieldLabel.Text = 'Li+ conc (mM)';
            app.conc1 = uieditfield(app.UIFigure, 'text');
            app.Mg2concmMEditFieldLabel = uilabel(app.UIFigure);
            app.Mg2concmMEditFieldLabel.Text = 'Mg2+ conc (mM)';
            app.conc2 = uieditfield(app.UIFigure, 'text');
            app.LirejectionEditFieldLabel = uilabel(app.UIFigure);
            app.LirejectionEditFieldLabel.Text = 'Li+ rejection (%)';
            app.rej1 = uieditfield(app.UIFigure, 'text');
            app.Mg2rejectionEditFieldLabel = uilabel(app.UIFigure);
            app.Mg2rejectionEditFieldLabel.Text = 'Mg2+ rejection (%)';
            app.rej2 = uieditfield(app.UIFigure, 'text');
            app.JvLMHEditFieldLabel = uilabel(app.UIFigure);
            app.JvLMHEditFieldLabel.Text = 'Jv (LMH)';
            app.jv = uieditfield(app.UIFigure, 'text');
            app.MasstransfercoefficientkLMHLabel = uilabel(app.UIFigure);
            app.MasstransfercoefficientkLMHLabel.Text = 'Mass transfer coefficient, k (LMH)';
            app.k = uieditfield(app.UIFigure, 'numeric');
            app.k.Value = 100;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Fitting ion permeability with SDEM model'; ''; 'Instructions:'; '1. Please enter data separated by space or comma'; '2. Pay attention to units'; '3. Click the ''Calculate'' button and results will display in the table'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDEMapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end